classdef PipeFlowPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        ControlsPanel                 matlab.ui.container.Panel
        ExportReportButton            matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        InputParametersPanel          matlab.ui.container.Panel
        EfficiencyEditField           matlab.ui.control.NumericEditField
        PumpEfficiencyLabel           matlab.ui.control.Label
        NumberofElbowsEditFieldLabel  matlab.ui.control.Label
        ElbowEditField                matlab.ui.control.NumericEditField
        EditField4Label               matlab.ui.control.Label
        ElevationEditField            matlab.ui.control.NumericEditField
        FluidDropDownLabel            matlab.ui.control.Label
        FluidDropDown                 matlab.ui.control.DropDown
        FlowRatem3sEditFieldLabel     matlab.ui.control.Label
        FlowRateEditField             matlab.ui.control.NumericEditField
        PipeDiametermLabel            matlab.ui.control.Label
        DiameterEditField             matlab.ui.control.NumericEditField
        PipeLengthmLabel              matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        PipeMaterialDropDownLabel     matlab.ui.control.Label
        MaterialDropDown              matlab.ui.control.DropDown
        ResultsPanel                  matlab.ui.container.Panel
        DesignScoreValueLabel         matlab.ui.control.Label
        OverallScoreLabel             matlab.ui.control.Label
        DesignAdvisorTextArea         matlab.ui.control.TextArea
        DesignAdvisorTextAreaLabel    matlab.ui.control.Label
        RecommendationValueLabel      matlab.ui.control.Label
        RecommendationLabel           matlab.ui.control.Label
        PumpPowerValueLabel           matlab.ui.control.Label
        PumpHeadValueLabel            matlab.ui.control.Label
        PressureDropValueLabel        matlab.ui.control.Label
        PumpPowerkWLabel              matlab.ui.control.Label
        RequiredPumpHeadmLabel        matlab.ui.control.Label
        PressureDropKPaLabel          matlab.ui.control.Label
        TotalLossValueLabel           matlab.ui.control.Label
        MinorLossValueLabel           matlab.ui.control.Label
        MajorLossValueLabel           matlab.ui.control.Label
        FrictionValueLabel            matlab.ui.control.Label
        FlowRegimeValueLabel          matlab.ui.control.Label
        ReynoldsValueLabel            matlab.ui.control.Label
        TotalHeadLossmLabel           matlab.ui.control.Label
        MinorHeadLossmLabel           matlab.ui.control.Label
        FrictionFactorLabel_2         matlab.ui.control.Label
        MajorHeadLossmLabel           matlab.ui.control.Label
        FlowRegimeLabel_2             matlab.ui.control.Label
        ReynoldsNumberLabel_2         matlab.ui.control.Label
        VelocityValueLabel            matlab.ui.control.Label
        VelocitymsLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Read User Inputs
            Q = app.FlowRateEditField.Value;          % Flow Rate (m^3/s)
            D = app.DiameterEditField.Value;          % Diameter (m)
            L = app.LengthEditField.Value;            % Length (m)
            H = app.ElevationEditField.Value;         % Elevation Difference (m)
            N = app.ElbowEditField.Value;             % Number of Elbows
            eta = app.EfficiencyEditField.Value/100;  % Pump Efficiency
            
            g = 9.81;
            %======================
            % Input Validation
            %======================

            if Q <= 0
                uialert(app.UIFigure,'Flow Rate must be greater than zero.','Input Error');
                return;
            end

            if D <= 0
                uialert(app.UIFigure,'Pipe Diameter must be greater than zero.','Input Error');
                return;
            end

            if L <= 0
                uialert(app.UIFigure,'Pipe Length must be greater than zero.','Input Error');
                return;
            end

            if eta <= 0 || eta > 1
                uialert(app.UIFigure,'Pump Efficiency must be between 1% and 100%.','Input Error');
                return;
            end

            if N < 0
                uialert(app.UIFigure,'Number of Elbows cannot be negative.','Input Error');
                return;
            end
            
            %======================
            % Fluid Properties
            %======================
            
            switch app.FluidDropDown.Value
            
                case 'Water'
                    rho = 1000;
                    mu = 0.001;
            
                case 'Steam'
                    rho = 0.60;
                    mu = 1.3e-5;
            
                case 'Engine Oil'
                    rho = 900;
                    mu = 0.25;
            
                case 'Diesel'
                    rho = 830;
                    mu = 0.003;
            
                case 'Air'
                    rho = 1.225;
                    mu = 1.81e-5;
            
                otherwise
                    rho = 1000;
                    mu = 0.001;
            
            end
            
            %======================
            % Pipe Roughness
            %======================
            
            switch app.MaterialDropDown.Value
            
                case 'Steel'
                    e = 0.000045;
            
                case 'PVC'
                    e = 0.0000015;
            
                case 'Copper'
                    e = 0.0000015;
            
                case 'Cast Iron'
                    e = 0.00026;
            
                case 'Stainless Steel'
                    e = 0.000015;
            
                otherwise
                    e = 0.000045;
            
            end
            
            %======================
            % Velocity
            %======================
            
            A = pi*D^2/4;
            V = Q/A;
            app.VelocityValueLabel.Text = sprintf('%.2f m/s',V);
            
            %======================
            % Reynolds Number
            %======================
            
            Re = rho*V*D/mu;
            app.ReynoldsValueLabel.Text = sprintf('%.0f',Re);
            
            %======================
            % Flow Regime
            %======================
            
            if Re < 2300
                regime = "Laminar";
            elseif Re < 4000
                regime = "Transition";
            else
                regime = "Turbulent";
            end
            
            app.FlowRegimeValueLabel.Text = regime;
            
            %======================
            % Friction Factor
            %======================
            
            if Re < 2300
                f = 64/Re;
            else
                f = 0.25/(log10((e/(3.7*D))+(5.74/(Re^0.9))))^2;
            end
            
            app.FrictionValueLabel.Text = sprintf('%.4f',f);
            
            %======================
            % Major Head Loss
            %======================
            
            MajorLoss = f*(L/D)*(V^2/(2*g));
            app.MajorLossValueLabel.Text = sprintf('%.2f m',MajorLoss);
            
            %======================
            % Minor Head Loss
            %======================
            
            K = 0.9;
            MinorLoss = N*K*(V^2/(2*g));
            app.MinorLossValueLabel.Text = sprintf('%.2f m',MinorLoss);
            
            %======================
            % Total Head Loss
            %======================
            
            TotalLoss = MajorLoss + MinorLoss;
            app.TotalLossValueLabel.Text = sprintf('%.2f m',TotalLoss);
            
            %======================
            % Pressure Drop
            %======================
            
            PressureDrop = rho*g*TotalLoss/1000;
            app.PressureDropValueLabel.Text = sprintf('%.2f kPa',PressureDrop);
            
            %======================
            % Pump Head
            %======================
            
            PumpHead = TotalLoss + H;
            app.PumpHeadValueLabel.Text = sprintf('%.2f m',PumpHead);
            
            %======================
            % Pump Power
            %======================
            
            PumpPower = (rho*g*Q*PumpHead)/(eta*1000);
            app.PumpPowerValueLabel.Text = sprintf('%.2f kW',PumpPower);

            if PressureDrop < 50

                recommendation = "Excellent Design";

            elseif PressureDrop < 150

                recommendation = "Acceptable Design";

            else

                recommendation = "High Pressure Loss - Increase Pipe Diameter";

            end

            app.RecommendationValueLabel.Text = recommendation;
            %======================
            % Engineering Recommendation
            %======================

            if V > 3

                recommendation = "Velocity too high - Increase pipe diameter.";

            elseif PressureDrop > 150

                recommendation = "High pressure drop - Check pipe sizing.";

            elseif PumpPower > 100

                recommendation = "Large pump required - Consider redesign.";

            elseif Re < 2300

                recommendation = "Laminar flow detected.";

            else

                recommendation = "Design is satisfactory.";

            end

            app.RecommendationValueLabel.Text = recommendation;
            %======================
            % Design Advisor
            %======================

            report = "";

            % Velocity Check
            if V < 0.6
                report = report + "⚠ Velocity is too low. Sedimentation may occur." + newline;
            elseif V > 3
                report = report + "⚠ Velocity is too high. Increase pipe diameter." + newline;
            else
                report = report + "✓ Velocity is acceptable." + newline;
            end

            % Flow Regime
            if Re < 2300
                report = report + "⚠ Laminar flow detected." + newline;
            elseif Re < 4000
                report = report + "⚠ Transition flow region." + newline;
            else
                report = report + "✓ Turbulent flow achieved." + newline;
            end

            % Pressure Drop
            if PressureDrop < 50
                report = report + "✓ Pressure drop is low." + newline;
            elseif PressureDrop < 150
                report = report + "✓ Pressure drop is acceptable." + newline;
            else
                report = report + "⚠ High pressure drop. Increase pipe diameter or reduce pipe length." + newline;
            end

            % Pump Efficiency
            if eta < 0.70
                report = report + "⚠ Pump efficiency is low." + newline;
            else
                report = report + "✓ Pump efficiency is acceptable." + newline;
            end

            % Pump Power
            if PumpPower > 100
                report = report + "⚠ Large pump required." + newline;
            else
                report = report + "✓ Pump size is reasonable." + newline;
            end
            score = 100;

            if V > 3 || V < 0.6
                score = score - 20;
            end

            if PressureDrop > 150
                score = score - 25;
            end

            if eta < 0.70
                score = score - 15;
            end

            if PumpPower > 100
                score = score - 20;
            end

            if Re < 2300
                score = score - 20;
            end

            score = max(score,0);

            app.DesignScoreValueLabel.Text = sprintf('%d / 100',score);

            app.DesignAdvisorTextArea.Value = splitlines(report);
            %======================
            % Pressure Drop Graph
            %======================

            Qplot = linspace(0.01,Q*1.5,30);

            PressurePlot = zeros(size(Qplot));

            for i = 1:length(Qplot)

                Vtemp = Qplot(i)/(pi*D^2/4);

                Retemp = rho*Vtemp*D/mu;

                if Retemp < 2300
                    ftemp = 64/Retemp;
                else
                    ftemp = 0.25/(log10((e/(3.7*D))+(5.74/(Retemp^0.9))))^2;
                end

                MajorTemp = ftemp*(L/D)*(Vtemp^2/(2*g));

                MinorTemp = N*0.9*(Vtemp^2/(2*g));

                TotalTemp = MajorTemp + MinorTemp;

                PressurePlot(i)=rho*g*TotalTemp/1000;

            end

            plot(app.UIAxes,Qplot,PressurePlot,'LineWidth',2)

            grid(app.UIAxes,'on')

            xlabel(app.UIAxes,'Flow Rate (m^3/s)')

            ylabel(app.UIAxes,'Pressure Drop (kPa)')

            title(app.UIAxes,'Pressure Drop vs Flow Rate')

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            %======================
            % Reset Inputs
            %======================

            app.FlowRateEditField.Value = 0;
            app.DiameterEditField.Value = 0;
            app.LengthEditField.Value = 0;
            app.ElevationEditField.Value = 0;
            app.ElbowEditField.Value = 0;
            app.EfficiencyEditField.Value = 0;

            app.FluidDropDown.Value = 'Water';
            app.MaterialDropDown.Value = 'Steel';

            %======================
            % Reset Results
            %======================

            app.VelocityValueLabel.Text = "--";
            app.ReynoldsValueLabel.Text = "--";
            app.FlowRegimeValueLabel.Text = "--";
            app.FrictionValueLabel.Text = "--";
            app.MajorLossValueLabel.Text = "--";
            app.MinorLossValueLabel.Text = "--";
            app.TotalLossValueLabel.Text = "--";
            app.PressureDropValueLabel.Text = "--";
            app.PumpHeadValueLabel.Text = "--";
            app.PumpPowerValueLabel.Text = "--";

            % If you added these
            app.RecommendationValueLabel.Text = "--";
            app.DesignScoreValueLabel.Text = "--";
            app.DesignAdvisorTextArea.Value = "Click Calculate...";

            %======================
            % Clear Graph
            %======================

            cla(app.UIAxes)

            title(app.UIAxes,"")

            xlabel(app.UIAxes,"")

            ylabel(app.UIAxes,"")
        end

        % Button pushed function: ExportReportButton
        function ExportReportButtonPushed(app, event)
            [file,path] = uiputfile('PipeFlow_Report.txt','Save Report');

            if isequal(file,0)
                return;
            end

            fid = fopen(fullfile(path,file),'w');

            fprintf(fid,"=============================================\n");
            fprintf(fid,"          PIPEFLOW PRO REPORT\n");
            fprintf(fid,"=============================================\n\n");

            fprintf(fid,"Generated : %s\n\n",datestr(now));

            fprintf(fid,"INPUT PARAMETERS\n");
            fprintf(fid,"---------------------------------------------\n");
            fprintf(fid,"Fluid                : %s\n",app.FluidDropDown.Value);
            fprintf(fid,"Pipe Material        : %s\n",app.MaterialDropDown.Value);
            fprintf(fid,"Flow Rate            : %.3f m^3/s\n",app.FlowRateEditField.Value);
            fprintf(fid,"Pipe Diameter        : %.3f m\n",app.DiameterEditField.Value);
            fprintf(fid,"Pipe Length          : %.2f m\n",app.LengthEditField.Value);
            fprintf(fid,"Elevation Difference : %.2f m\n",app.ElevationEditField.Value);
            fprintf(fid,"Number of Elbows     : %.0f\n",app.ElbowEditField.Value);
            fprintf(fid,"Pump Efficiency      : %.1f %%\n\n",app.EfficiencyEditField.Value);

            fprintf(fid,"RESULTS\n");
            fprintf(fid,"---------------------------------------------\n");
            fprintf(fid,"Velocity             : %s\n",app.VelocityValueLabel.Text);
            fprintf(fid,"Reynolds Number      : %s\n",app.ReynoldsValueLabel.Text);
            fprintf(fid,"Flow Regime          : %s\n",app.FlowRegimeValueLabel.Text);
            fprintf(fid,"Friction Factor      : %s\n",app.FrictionValueLabel.Text);
            fprintf(fid,"Major Head Loss      : %s\n",app.MajorLossValueLabel.Text);
            fprintf(fid,"Minor Head Loss      : %s\n",app.MinorLossValueLabel.Text);
            fprintf(fid,"Total Head Loss      : %s\n",app.TotalLossValueLabel.Text);
            fprintf(fid,"Pressure Drop        : %s\n",app.PressureDropValueLabel.Text);
            fprintf(fid,"Required Pump Head   : %s\n",app.PumpHeadValueLabel.Text);
            fprintf(fid,"Pump Power           : %s\n",app.PumpPowerValueLabel.Text);

            fprintf(fid,"\n=============================================\n");
            fprintf(fid,"Generated by PipeFlow Pro\n");
            fprintf(fid,"Mechanical Engineering Hydraulic Analysis Tool\n");
            fprintf(fid,"=============================================\n");

            fclose(fid);

            uialert(app.UIFigure,"Report exported successfully!","Success");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pressure vs  Flow Rate')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.VelocitymsLabel = uilabel(app.ResultsPanel);
            app.VelocitymsLabel.Text = 'Velocity(m/s)';
            app.VelocityValueLabel = uilabel(app.ResultsPanel);
            app.VelocityValueLabel.Text = '--';
            app.ReynoldsNumberLabel_2 = uilabel(app.ResultsPanel);
            app.ReynoldsNumberLabel_2.Text = 'Reynolds Number';
            app.FlowRegimeLabel_2 = uilabel(app.ResultsPanel);
            app.FlowRegimeLabel_2.Text = 'Flow Regime';
            app.MajorHeadLossmLabel = uilabel(app.ResultsPanel);
            app.MajorHeadLossmLabel.Text = 'Major Head Loss(m)';
            app.FrictionFactorLabel_2 = uilabel(app.ResultsPanel);
            app.FrictionFactorLabel_2.Text = 'Friction Factor';
            app.MinorHeadLossmLabel = uilabel(app.ResultsPanel);
            app.MinorHeadLossmLabel.Text = 'Minor Head Loss(m)';
            app.TotalHeadLossmLabel = uilabel(app.ResultsPanel);
            app.TotalHeadLossmLabel.Text = 'Total Head Loss(m)';
            app.ReynoldsValueLabel = uilabel(app.ResultsPanel);
            app.ReynoldsValueLabel.Text = '--';
            app.FlowRegimeValueLabel = uilabel(app.ResultsPanel);
            app.FlowRegimeValueLabel.Text = '--';
            app.FrictionValueLabel = uilabel(app.ResultsPanel);
            app.FrictionValueLabel.Text = '--';
            app.MajorLossValueLabel = uilabel(app.ResultsPanel);
            app.MajorLossValueLabel.Text = '--';
            app.MinorLossValueLabel = uilabel(app.ResultsPanel);
            app.MinorLossValueLabel.Text = '--';
            app.TotalLossValueLabel = uilabel(app.ResultsPanel);
            app.TotalLossValueLabel.Text = '--';
            app.PressureDropKPaLabel = uilabel(app.ResultsPanel);
            app.PressureDropKPaLabel.Text = 'Pressure Drop(KPa)';
            app.RequiredPumpHeadmLabel = uilabel(app.ResultsPanel);
            app.RequiredPumpHeadmLabel.Text = 'Required Pump Head(m)';
            app.PumpPowerkWLabel = uilabel(app.ResultsPanel);
            app.PumpPowerkWLabel.Text = 'Pump Power(kW)';
            app.PressureDropValueLabel = uilabel(app.ResultsPanel);
            app.PressureDropValueLabel.Text = '--';
            app.PumpHeadValueLabel = uilabel(app.ResultsPanel);
            app.PumpHeadValueLabel.Text = '--';
            app.PumpPowerValueLabel = uilabel(app.ResultsPanel);
            app.PumpPowerValueLabel.Text = '--';
            app.RecommendationLabel = uilabel(app.ResultsPanel);
            app.RecommendationLabel.Text = 'Recommendation';
            app.RecommendationValueLabel = uilabel(app.ResultsPanel);
            app.RecommendationValueLabel.Text = '--';
            app.DesignAdvisorTextAreaLabel = uilabel(app.ResultsPanel);
            app.DesignAdvisorTextAreaLabel.Text = 'Design Advisor';
            app.DesignAdvisorTextArea = uitextarea(app.ResultsPanel);
            app.DesignAdvisorTextArea.Value = {'Click Calculate...'};
            app.OverallScoreLabel = uilabel(app.ResultsPanel);
            app.OverallScoreLabel.Text = 'OverallScore';
            app.DesignScoreValueLabel = uilabel(app.ResultsPanel);
            app.DesignScoreValueLabel.Text = '--';
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.MaterialDropDown = uidropdown(app.InputParametersPanel);
            app.MaterialDropDown.Items = {'Steel', 'Cast Iron', 'PVC', 'Copper', 'Stainless Steel'};
            app.MaterialDropDown.Value = 'Steel';
            app.PipeMaterialDropDownLabel = uilabel(app.InputParametersPanel);
            app.PipeMaterialDropDownLabel.Text = 'Pipe Material';
            app.LengthEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.PipeLengthmLabel = uilabel(app.InputParametersPanel);
            app.PipeLengthmLabel.Text = 'Pipe Length(m)';
            app.DiameterEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.PipeDiametermLabel = uilabel(app.InputParametersPanel);
            app.PipeDiametermLabel.Text = 'Pipe Diameter(m)';
            app.FlowRateEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.FlowRatem3sEditFieldLabel = uilabel(app.InputParametersPanel);
            app.FlowRatem3sEditFieldLabel.Text = 'Flow Rate(m3/s)';
            app.FluidDropDown = uidropdown(app.InputParametersPanel);
            app.FluidDropDown.Items = {'Water', 'Steam', 'Engine Oil', 'Diesel', 'Air'};
            app.FluidDropDown.Value = 'Water';
            app.FluidDropDownLabel = uilabel(app.InputParametersPanel);
            app.FluidDropDownLabel.Text = 'Fluid';
            app.ElevationEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.EditField4Label = uilabel(app.InputParametersPanel);
            app.EditField4Label.Text = 'Elevation Difference(m)';
            app.ElbowEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.NumberofElbowsEditFieldLabel = uilabel(app.InputParametersPanel);
            app.NumberofElbowsEditFieldLabel.Text = 'Number of Elbows';
            app.PumpEfficiencyLabel = uilabel(app.InputParametersPanel);
            app.PumpEfficiencyLabel.Text = 'Pump Efficiency %';
            app.EfficiencyEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.ResetButton = uibutton(app.ControlsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CalculateButton = uibutton(app.ControlsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ExportReportButton = uibutton(app.ControlsPanel, 'push');
            app.ExportReportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReportButtonPushed, true);
            app.ExportReportButton.Text = 'Export Report';
            app.Panel = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel);
            app.Label.Text = {'                            PipeFlow Pro'; '         Pipe Network Pressure Loss Calculator'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipeFlowPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end